classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        xtEditFieldLabel  matlab.ui.control.Label
        xtEditField       matlab.ui.control.EditField
        ytEditFieldLabel  matlab.ui.control.Label
        ytEditField       matlab.ui.control.EditField
        ztEditFieldLabel  matlab.ui.control.Label
        ztEditField       matlab.ui.control.EditField
        tLabel            matlab.ui.control.Label
        tEditField        matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        XLabel            matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        YLabel            matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        Label_10          matlab.ui.control.Label
        ZLabel            matlab.ui.control.Label
        Label_11          matlab.ui.control.Label
        Label_12          matlab.ui.control.Label
        Label_13          matlab.ui.control.Label
        XLabel_2          matlab.ui.control.Label
        Label_14          matlab.ui.control.Label
        Label_15          matlab.ui.control.Label
        Label_16          matlab.ui.control.Label
        YLabel_2          matlab.ui.control.Label
        Label_18          matlab.ui.control.Label
        Label_19          matlab.ui.control.Label
        ZLabel_2          matlab.ui.control.Label
        Label_20          matlab.ui.control.Label
        Label_21          matlab.ui.control.Label
        Label_22          matlab.ui.control.Label
        Label_17          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;  
            syms t;
            syms x y z;
            
            xt = str2sym(app.xtEditField.Value);
            yt = str2sym(app.ytEditField.Value);
            zt = str2sym(app.ztEditField.Value);
            
            t0 = str2double(app.tEditField.Value);
            
            x0 = double(subs(xt,t,t0));
            y0 = double(subs(yt,t,t0));
            z0 = double(subs(zt,t,t0));
            x1 = double(subs(diff(xt,1,t),t,t0));
            y1 = double(subs(diff(yt,1,t),t,t0));
            z1 = double(subs(diff(zt,1,t),t,t0));
            
            fplot3(app.UIAxes,xt,yt,zt);
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot3(app.UIAxes,x0,y0,z0,'*');

%             N = simplify((x-x0).*x1+(y-y0).*y1+(z-z0).*z1);
%             interval = [double(x0-10) double(x0+10) double(y0-10) double(y0+10) double(z0-10) double(z0+10)];
%             fimplicit3(app.UIAxes,N,interval);


            app.Label_14.Text = num2str(x0);
            app.Label_16.Text = num2str(x1);
            app.Label_17.Text = num2str(y0);
            app.Label_19.Text = num2str(y1);
            app.Label_20.Text = num2str(z0);
            app.Label_22.Text = num2str(z1);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.xtEditFieldLabel = uilabel(app.UIFigure);
            app.xtEditFieldLabel.Text = 'x(t)';
            app.xtEditField = uieditfield(app.UIFigure, 'text');
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.ztEditFieldLabel = uilabel(app.UIFigure);
            app.ztEditFieldLabel.Text = 'z(t)';
            app.ztEditField = uieditfield(app.UIFigure, 'text');
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = 't  =';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Уравнение нормали:';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X -';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Уравнение касательной:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '/';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = '=  Y  -';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '/';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = '=  Z  -';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '/';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X -';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '/';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = '=  Y  -';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '/';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '';
            app.ZLabel_2 = uilabel(app.UIFigure);
            app.ZLabel_2.Text = '=  Z  -';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '/';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end