classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        xtLabel           matlab.ui.control.Label
        xtEditField       matlab.ui.control.EditField
        ytEditFieldLabel  matlab.ui.control.Label
        ytEditField       matlab.ui.control.EditField
        tEditFieldLabel   matlab.ui.control.Label
        tEditField        matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        yLabel            matlab.ui.control.Label
        Label             matlab.ui.control.Label
        xLabel            matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        yLabel_2          matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        xLabel_2          matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;  
            syms t;
            xt = str2sym(app.xtEditField.Value);
            yt = str2sym(app.ytEditField.Value);
            
            t0 = str2double(app.tEditField.Value);
            x0 = double(subs(xt,t,t0));
            y0 = double(subs(yt,t,t0));
            
            fplot(app.UIAxes,xt,yt);
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot(app.UIAxes,x0,y0,'+');
            
            x1 = double(subs(diff(xt,1,t),t,t0));
            
            K = @(x) x1.*(x-x0) + y0;
            interval = [x0-10,x0+10];
            fplot(app.UIAxes,interval, K, 'g');
            
            N = @(x) (-1./x1).*(x-x0) + y0;
            fplot(app.UIAxes,interval, N, 'b');
            
            app.Label.Text = num2str(x1);
            app.Label_2.Text = num2str(y0-x1.*x0);
            
            app.Label_3.Text = num2str(-1./x1);
            app.Label_4.Text = num2str(y0+(-1./x1).*x0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'ось абсцисс')
            ylabel(app.UIAxes, 'ось ординат')
            app.xtLabel = uilabel(app.UIFigure);
            app.xtLabel.Text = 'x(t)  = ';
            app.xtEditField = uieditfield(app.UIFigure, 'text');
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)  =';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't  =';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y = ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x  +';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y =';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x  +';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Уравнение касательной:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Уравнение нормали:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end